classdef TUNE611 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TUNEUIFigure                    matlab.ui.Figure
        ZerotopeakSPLCheckBox           matlab.ui.control.CheckBox
        SetupfileEditField              matlab.ui.control.EditField
        SetupfileEditFieldLabel         matlab.ui.control.Label
        LowpasscutoffHzLabel            matlab.ui.control.Label
        nofilterLabel                   matlab.ui.control.Label
        infendoffileLabel               matlab.ui.control.Label
        v611Label                       matlab.ui.control.Label
        CalibrationfileEditField        matlab.ui.control.EditField
        OutputfolderEditField           matlab.ui.control.EditField
        InputfolderEditField            matlab.ui.control.EditField
        ExitstatusTextArea              matlab.ui.control.TextArea
        ErrorLamp                       matlab.ui.control.Lamp
        DoneLamp                        matlab.ui.control.Lamp
        RunningLamp                     matlab.ui.control.Lamp
        RunButton                       matlab.ui.control.Button
        AddsetupCheckBox                matlab.ui.control.CheckBox
        LogtofileCheckBox               matlab.ui.control.CheckBox
        LogtypeDropDown                 matlab.ui.control.DropDown
        PauseafterplotsEditField        matlab.ui.control.NumericEditField
        FreesizepositionCheckBox        matlab.ui.control.CheckBox
        FigureheightpixEditField        matlab.ui.control.NumericEditField
        FigurewidthpixEditField         matlab.ui.control.NumericEditField
        AutoYCheckBox                   matlab.ui.control.CheckBox
        YmaxdBEditField                 matlab.ui.control.NumericEditField
        YmindBEditField                 matlab.ui.control.NumericEditField
        AutoXCheckBox                   matlab.ui.control.CheckBox
        XmaxHzEditField                 matlab.ui.control.NumericEditField
        XminHzEditField                 matlab.ui.control.NumericEditField
        LowpasscutoffHzEditField        matlab.ui.control.NumericEditField
        HighpasscutoffHzEditField       matlab.ui.control.NumericEditField
        toSSSpinner                     matlab.ui.control.Spinner
        fromSSSpinner                   matlab.ui.control.Spinner
        KnudsencurvesCheckBox           matlab.ui.control.CheckBox
        AllPSDlinesCheckBox             matlab.ui.control.CheckBox
        LegendCheckBox                  matlab.ui.control.CheckBox
        TitleCheckBox                   matlab.ui.control.CheckBox
        LowpercentileSpinner            matlab.ui.control.Spinner
        HighpercentileSpinner           matlab.ui.control.Spinner
        AveragingDropDown               matlab.ui.control.DropDown
        LastDDbandDropDown              matlab.ui.control.DropDown
        FirstDDbandDropDown             matlab.ui.control.DropDown
        FFToverlapSpinner               matlab.ui.control.Spinner
        WindowDropDown                  matlab.ui.control.DropDown
        FFTpointsDropDown               matlab.ui.control.DropDown
        SnapshotoverlapSpinner          matlab.ui.control.Spinner
        SnapshotskipsEditField          matlab.ui.control.NumericEditField
        SnapshotlengthsEditField        matlab.ui.control.NumericEditField
        StoptimesEditField              matlab.ui.control.NumericEditField
        StarttimesEditField             matlab.ui.control.NumericEditField
        SaveTXTfilesCheckBox            matlab.ui.control.CheckBox
        SaveJPEGfilesCheckBox           matlab.ui.control.CheckBox
        BroadbandPSDCheckBox            matlab.ui.control.CheckBox
        BoxplotCheckBox                 matlab.ui.control.CheckBox
        DecidecadeSPLCheckBox           matlab.ui.control.CheckBox
        BroadbandSPLCheckBox            matlab.ui.control.CheckBox
        SaveButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        SelectButton_2                  matlab.ui.control.Button
        SelectButton_3                  matlab.ui.control.Button
        SelectButton                    matlab.ui.control.Button
        LogtypeDropDownLabel            matlab.ui.control.Label
        WindowDropDownLabel             matlab.ui.control.Label
        AveragingDropDownLabel          matlab.ui.control.Label
        ExitstatusTextAreaLabel         matlab.ui.control.Label
        ErrorLampLabel                  matlab.ui.control.Label
        DoneLabel                       matlab.ui.control.Label
        RunningLampLabel                matlab.ui.control.Label
        OutputfolderEditFieldLabel      matlab.ui.control.Label
        CalibrationfileEditFieldLabel   matlab.ui.control.Label
        InputfolderEditFieldLabel       matlab.ui.control.Label
        PauseafterplotsEditFieldLabel   matlab.ui.control.Label
        toSSSpinnerLabel                matlab.ui.control.Label
        fromSSSpinnerLabel              matlab.ui.control.Label
        FigurewidthpixEditFieldLabel    matlab.ui.control.Label
        FigureheightpixEditFieldLabel   matlab.ui.control.Label
        YmaxdBEditFieldLabel            matlab.ui.control.Label
        XmaxHzEditFieldLabel            matlab.ui.control.Label
        YmindBEditFieldLabel            matlab.ui.control.Label
        XminHzEditFieldLabel            matlab.ui.control.Label
        LastDDbandDropDownLabel         matlab.ui.control.Label
        FirstDDbandDropDownLabel        matlab.ui.control.Label
        LowpercentileSpinnerLabel       matlab.ui.control.Label
        HighpercentileSpinnerLabel      matlab.ui.control.Label
        HighpasscutoffHzEditFieldLabel  matlab.ui.control.Label
        FFToverlapSpinnerLabel          matlab.ui.control.Label
        FFTpointsDropDownLabel          matlab.ui.control.Label
        SnapshotskipsEditFieldLabel     matlab.ui.control.Label
        SnapoverlapLabel                matlab.ui.control.Label
        SnapshotlengthsEditFieldLabel   matlab.ui.control.Label
        StoptimesEditFieldLabel         matlab.ui.control.Label
        StarttimesEditFieldLabel        matlab.ui.control.Label
    end

% T U N E - Tool for Underwater Noise Evaluation
%    Graphical User Interface for option and parameter input, calling
%    main function 'TUNE<nnn>main' where <nnn> is version number.
%    Requires M-files 'TUNE<nnn>main', 'TUNEsub<nn>' in same folder.
%    Development is through Matlab's App Designer R2018b or later.
%    For details see user manual and Wiki pages on GitHub repository:
%    <https://github.com/sbuogo/TUNE>.
%
% FILE VERSIONS
%    - This file: 6.11 (update GUI label text upon change)
%    - Main function: TUNE611main
%    - FFT subroutine: TUNEsub41 (new name since 6.11)
%
% BUGS FIXED
%    Selecting setup file created with other versions now possible,
%    though file is loaded only if setup parameters match otherwise
%    a warning message is raised and setup is unchanged.
%    
% COPYRIGHT
%    This program is free software: you can redistribute it and/or modify
%    it under the terms of the GNU General Public License as published by
%    the Free Software Foundation, either version 3 of the License, or
%    any later version.
%    This program is distributed in the hope that it will be useful,
%    but WITHOUT ANY WARRANTY; without even the implied warranty of
%    MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE. See GNU General
%    Public License for more details  <https://www.gnu.org/licenses/>.
%
% AUTHORS
%   Silvano Buogo, Junio Fabrizio Borsani, Valentina Caradonna
%   <silvano.buogo@cnr.it>

    properties (Access = private)
        Setup       % parameter structure excluding folders & files
        setupFileName 
        setupFilePath
        setupFileExt = '*.mat';
        calFileName 
        calFilePath
        calFileExt = '*.txt';
        inputPath
        outputPath
        setupPath
        pathSep
        returnVal
        exitStat
        figHandles
        logFileName = 'TUNE_log.txt'; % must match with def in main
        initSetup   % structure for in/out folders and cal/setup files
        initFileName = 'TUNE_ini.mat';
        initFolder = '.TUNE'; % created in home folder to save init file
        initFilePath
        defaultFolders  % true=1 or false=0
        homePath  % init file is saved in writable home folder
        runNumber
        calFigHandle
        calFigID = 8; % make sure ID is not used for other plots
        calFigPos = [865 200 640 480]; % match with other figures

        % need parameter names to retrieve from *.mat file into workspace
        setupParams = { ...
            "PROGVERS", ... % used to check for correct file version
            "PROCSPLB", ...
            "PROCPSDB", ...
            "PROCSPLD", ...
            "PROCSPLP", ... % added in v. 6.11
            "BOXPLT", ...
            "STARTTIME", ...
            "STOPTIME", ...
            "SNAPLEN", ...
            "SNAPOVLAP", ...
            "SNAPSKIP", ...
            "NFFT", ...
            "FFTOVLAP", ...
            "WTYPE", ...
            "AVGTYPE", ...
            "HIPASSF", ...
            "LOPASSF", ...
            "PRCTILEL", ...
            "PRCTILEH", ...
            "DDIDXL", ...
            "DDIDXH", ...
            "DRAWALL", ...
            "SAVEJPG", ...
            "SAVETXT", ...
            "XAXAUTO", ...
            "YAXAUTO", ...
            "XAXMIN", ...
            "XAXMAX", ...
            "YAXMIN", ...
            "YAXMAX", ...
            "LEGND", ...
            "TITL", ...
            "FIGSIZE", ...
            "NORESIZ", ...
            "VERB", ...
            "PLTPAUSE", ...
            "KNDSDRAW", ...
            "KNDSSIDX", ...
            "LOGFILE", ...
            "LOGSETUP"
            };

            initFoldersFiles = {"INPATH","OUTPATH","CALFPATH", ...
                                   "SETUPFPATH"};
    end
    
    methods (Access = private)
        
        function createSetup(app) % using values of controls in GUI
            % NOTE: convert checkbox values to boolean using > 0
            app.Setup.PROGVERS = app.v611Label.Text;
            app.Setup.PROCSPLB = app.BroadbandSPLCheckBox.Value > 0;
            app.Setup.PROCPSDB = app.BroadbandPSDCheckBox.Value > 0;
            app.Setup.PROCSPLD = app.DecidecadeSPLCheckBox.Value > 0; 
            app.Setup.PROCSPLP = app.ZerotopeakSPLCheckBox.Value > 0; % >v.6.11
            app.Setup.BOXPLT = app.BoxplotCheckBox.Value > 0;
            app.Setup.STARTTIME = app.StarttimesEditField.Value;
            app.Setup.STOPTIME = app.StoptimesEditField.Value;
            app.Setup.SNAPLEN = app.SnapshotlengthsEditField.Value;
            app.Setup.SNAPOVLAP = app.SnapshotoverlapSpinner.Value;
            app.Setup.SNAPSKIP = app.SnapshotskipsEditField.Value;
            app.Setup.NFFT = 2^str2num(app.FFTpointsDropDown.Value);
            app.Setup.FFTOVLAP = app.FFToverlapSpinner.Value;
            app.Setup.WTYPE = str2num(app.WindowDropDown.Value);
            app.Setup.AVGTYPE = str2num(app.AveragingDropDown.Value);
            app.Setup.HIPASSF = app.HighpasscutoffHzEditField.Value;
            app.Setup.LOPASSF = app.LowpasscutoffHzEditField.Value;
            app.Setup.PRCTILEL = app.LowpercentileSpinner.Value;
            app.Setup.PRCTILEH = app.HighpercentileSpinner.Value;
            app.Setup.DDIDXL = str2num(app.FirstDDbandDropDown.Value);
            app.Setup.DDIDXH = str2num(app.LastDDbandDropDown.Value);
            app.Setup.DRAWALL = app.AllPSDlinesCheckBox.Value > 0;
            app.Setup.SAVEJPG = app.SaveJPEGfilesCheckBox.Value > 0;
            app.Setup.SAVETXT = app.SaveTXTfilesCheckBox.Value > 0;
            app.Setup.XAXAUTO = app.AutoXCheckBox.Value > 0;
            app.Setup.YAXAUTO = app.AutoYCheckBox.Value > 0;
            app.Setup.XAXMIN = app.XminHzEditField.Value;
            app.Setup.XAXMAX = app.XmaxHzEditField.Value;
            app.Setup.YAXMIN = app.YmindBEditField.Value;
            app.Setup.YAXMAX = app.YmaxdBEditField.Value;
            app.Setup.LEGND = app.LegendCheckBox.Value > 0;
            app.Setup.TITL = app.TitleCheckBox.Value > 0;
            app.Setup.FIGSIZE = [app.FigurewidthpixEditField.Value ...
                app.FigureheightpixEditField.Value];
            app.Setup.NORESIZ = app.FreesizepositionCheckBox.Value > 0;
            app.Setup.VERB = str2num(app.LogtypeDropDown.Value);
            app.Setup.PLTPAUSE = app.PauseafterplotsEditField.Value;
            app.Setup.KNDSDRAW = app.KnudsencurvesCheckBox.Value > 0;
            app.Setup.KNDSSIDX =  app.fromSSSpinner.Value : ...
                app.toSSSpinner.Value ;
            app.Setup.LOGFILE = app.LogtofileCheckBox.Value > 0;
            app.Setup.LOGSETUP = app.AddsetupCheckBox.Value > 0;
            % next three are set by button callbacks
            app.Setup.INPATH = app.inputPath; 
            app.Setup.CALFPATH = [app.calFilePath,app.calFileName]; 
            app.Setup.OUTPATH = app.outputPath;
        end
        
        function valid = readSetup(app) % and update GUI controls
            text1 = "Setup file created with different program version";
            text2 = "Some parameters do not match: enter them manually";
            text3 = "Valid parameters, updated";
            text4 = "Invalid setup file";
            if ~isfield(app.Setup,'PROGVERS')
                msgbox(text4,'Error','error','modal');
                valid = 0;
                return % exit function, do not update controls
            end
            try
                app.BroadbandSPLCheckBox.Value = app.Setup.PROCSPLB;
                app.BroadbandPSDCheckBox.Value = app.Setup.PROCPSDB;
                app.DecidecadeSPLCheckBox.Value = app.Setup.PROCSPLD; 
                app.ZerotopeakSPLCheckBox.Value = app.Setup.PROCSPLP; % >v. 6.11
                app.BoxplotCheckBox.Value = app.Setup.BOXPLT;
                app.StarttimesEditField.Value = app.Setup.STARTTIME;
                app.StoptimesEditField.Value = app.Setup.STOPTIME;
                app.SnapshotlengthsEditField.Value = app.Setup.SNAPLEN;
                app.SnapshotoverlapSpinner.Value = app.Setup.SNAPOVLAP;
                app.SnapshotskipsEditField.Value = app.Setup.SNAPSKIP;
                app.FFTpointsDropDown.Value = num2str(log2(app.Setup.NFFT));
                app.FFToverlapSpinner.Value = app.Setup.FFTOVLAP;
                app.WindowDropDown.Value = num2str(app.Setup.WTYPE);
                app.AveragingDropDown.Value = num2str(app.Setup.AVGTYPE);
                app.HighpasscutoffHzEditField.Value = app.Setup.HIPASSF;
                app.LowpasscutoffHzEditField.Value = app.Setup.LOPASSF;
                app.LowpercentileSpinner.Value = app.Setup.PRCTILEL;
                app.HighpercentileSpinner.Value = app.Setup.PRCTILEH;
                app.FirstDDbandDropDown.Value = num2str(app.Setup.DDIDXL);
                app.LastDDbandDropDown.Value = num2str(app.Setup.DDIDXH);
                app.AllPSDlinesCheckBox.Value = app.Setup.DRAWALL;
                app.SaveJPEGfilesCheckBox.Value = app.Setup.SAVEJPG;
                app.SaveTXTfilesCheckBox.Value = app.Setup.SAVETXT;
                app.AutoXCheckBox.Value = app.Setup.XAXAUTO;
                app.AutoYCheckBox.Value = app.Setup.YAXAUTO;
                app.XminHzEditField.Value = app.Setup.XAXMIN;
                app.XmaxHzEditField.Value = app.Setup.XAXMAX;
                app.YmindBEditField.Value = app.Setup.YAXMIN;
                app.YmaxdBEditField.Value = app.Setup.YAXMAX;
                app.LegendCheckBox.Value = app.Setup.LEGND;
                app.TitleCheckBox.Value = app.Setup.TITL;
                figureSize = app.Setup.FIGSIZE;
                app.FigurewidthpixEditField.Value = figureSize(1);
                app.FigureheightpixEditField.Value = figureSize(2);
                app.FreesizepositionCheckBox.Value = app.Setup.NORESIZ;
                app.LogtypeDropDown.Value = num2str(app.Setup.VERB);
                app.PauseafterplotsEditField.Value = app.Setup.PLTPAUSE;
                app.KnudsencurvesCheckBox.Value = app.Setup.KNDSDRAW;
                knudsenRange = app.Setup.KNDSSIDX;
                app.fromSSSpinner.Value = knudsenRange(1);
                app.toSSSpinner.Value = knudsenRange(length(knudsenRange));
                app.LogtofileCheckBox.Value = app.Setup.LOGFILE;
                app.AddsetupCheckBox.Value = app.Setup.LOGSETUP;
                valid = 1;
            catch
                % msgbox([text1;text2],'Warning','warn','modal');
                valid = 0;
            end
            if strcmp(app.Setup.PROGVERS,app.v611Label.Text) == 0 % diff.v.
                if valid == 1
                    msgbox([text1;text3],'Info','help','modal'); % valid
                else
                    msgbox([text1;text2],'Warning','warn','modal'); % no
                end
            else
                msgbox(text3,'Info','help','modal'); % same vers., ok
            end
        end

        % next functions need to be called both by user events 
        % and upon setup load

        function updateLogAddSetupCheckBox(app)
            value = app.LogtofileCheckBox.Value;
            if value == 0
                app.LogtypeDropDown.Enable = "off";
                app.AddsetupCheckBox.Enable = "off";
            else
                app.LogtypeDropDown.Enable = "on";
                app.AddsetupCheckBox.Enable = "on";
            end
        end

        function updateXmaxminFields(app)
            value = app.AutoXCheckBox.Value;
            if value > 0
                app.XmaxHzEditField.Enable = "off";
                app.XminHzEditField.Enable = "off";
            else
                app.XmaxHzEditField.Enable = "on";
                app.XminHzEditField.Enable = "on";
            end
        end

        function updateYmaxminFields(app)
            value = app.AutoYCheckBox.Value;
            if value > 0
                app.YmaxdBEditField.Enable = "off";
                app.YmindBEditField.Enable = "off";
            else
                app.YmaxdBEditField.Enable = "on";
                app.YmindBEditField.Enable = "on";
            end
        end

        function updateFromtoSSSpinners(app)
            value = app.KnudsencurvesCheckBox.Value;
            if value == 0
                app.fromSSSpinner.Enable = "off";
                app.toSSSpinner.Enable = "off";
            else
                app.fromSSSpinner.Enable = "on";
                app.toSSSpinner.Enable = "on";
            end
        end

        function updateBoxplotDDBandOptions(app)
            value = app.DecidecadeSPLCheckBox.Value;
            if value == 0
                app.BoxplotCheckBox.Enable = "off";
                app.FirstDDbandDropDown.Enable = "off";
                app.LastDDbandDropDown.Enable = "off";
            else
                app.BoxplotCheckBox.Enable = "on";
                app.FirstDDbandDropDown.Enable = "on";
                app.LastDDbandDropDown.Enable = "on";
            end
        end

        function updateAllPSDlinesKnudsenOptions(app)
            value = app.BroadbandPSDCheckBox.Value;
            if value == 0
                app.AllPSDlinesCheckBox.Enable = "off";
                app.KnudsencurvesCheckBox.Enable = "off";
            else
                app.AllPSDlinesCheckBox.Enable = "on";
                app.KnudsencurvesCheckBox.Enable = "on";
            end
        end

        function updateFigureheightwidthOptions(app)
            value = app.FreesizepositionCheckBox.Value;
            if value == 0
                app.FigureheightpixEditField.Enable = "on";
                app.FigurewidthpixEditField.Enable = "on";
            else
                app.FigureheightpixEditField.Enable = "off";
                app.FigurewidthpixEditField.Enable = "off";
            end
        end

        function updatePercentileOptions(app)
            box  = app.BoxplotCheckBox.Value;
            high = app.HighpercentileSpinner.Value;
            low  = app.LowpercentileSpinner.Value;
            if box == 0
                app.HighpercentileSpinner.Enable = "on";
                app.LowpercentileSpinner.Enable = "on";
                app.HighpercentileSpinner.Value = high;
                app.LowpercentileSpinner.Value = low;
            else
                app.HighpercentileSpinner.Enable = "off";
                app.LowpercentileSpinner.Enable = "off";
                app.HighpercentileSpinner.Value = 75;
                app.LowpercentileSpinner.Value = 25;
            end
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.runNumber = 0; % reset run counter
            % set path separator and home path according to system
            if ispc  
                app.pathSep = '\';
                app.homePath = [getenv('HOMEDRIVE') getenv('HOMEPATH')];
            else
                app.pathSep = '/';
                app.homePath = getenv('HOME');
            end

            defaultFolder = pwd; % default if no init file available

            % create subfolder in home folder if not already present
            initFolderPath = [app.homePath app.pathSep app.initFolder];
            if ~isfolder(initFolderPath)
                mkdir(initFolderPath);
            end

            % if init file from previous session is found in home subfolder 
            % update work folders & files
            app.initFilePath = [initFolderPath app.pathSep ...
                                app.initFileName];
            if isfile(app.initFilePath) 
                app.initSetup = load(app.initFilePath,...
                                     app.initFoldersFiles{:});
                app.inputPath = app.initSetup.INPATH;
                app.outputPath = app.initSetup.OUTPATH;
                app.calFilePath = app.initSetup.CALFPATH;
                app.setupFilePath = app.initSetup.SETUPFPATH;
                if exist(string(app.setupFilePath),'dir') == 0
                    % previous session ended normally but with no setup,
                    % init file field would be zero in this case
                    app.setupFilePath = defaultFolder;
                end
                app.defaultFolders = 0;
            else % no init file, use default
                app.inputPath = defaultFolder;
                app.outputPath = defaultFolder;
                app.calFilePath = defaultFolder;
                app.setupFilePath = defaultFolder;
                app.defaultFolders = 1;
            end
            app.InputfolderEditField.Value = app.inputPath;
            app.OutputfolderEditField.Value = app.outputPath;
            app.CalibrationfileEditField.Value = app.calFilePath;
            app.SetupfileEditField.Value = app.setupFilePath;

            app.RunningLamp.Color = [0 0 0];
            app.DoneLamp.Color = [0 0 0];
            app.ErrorLamp.Color = [0 0 0];
            app.ExitstatusTextArea.Value = '';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.runNumber = app.runNumber + 1; % increase counter
            % Setup parameters to be passed to main function
            % are updated using values of controls in gui
            createSetup(app);

            % If 'keep figure size&pos.' is on, silently pass option=off
            % on first run so figures are tiled instead of stacked
            if app.runNumber == 1
                app.Setup.NORESIZ = 0;
            else
                app.Setup.NORESIZ = app.FreesizepositionCheckBox.Value>0;
            end
            
            app.ExitstatusTextArea.Value = '';
            app.DoneLamp.Color = [0 0 0];
            app.ErrorLamp.Color = [0 0 0];
            app.RunningLamp.Color = [0 1 0]; % green
            app.RunButton.Enable = "off";

            %%%%%%%%%%%%%%%%%%%% exec main function %%%%%%%%%%%%%%%%%%%%%
            [app.returnVal,app.exitStat,app.figHandles] = ...
                TUNE611main(app.Setup); 
            
            app.ExitstatusTextArea.Value = app.exitStat;
            app.RunningLamp.Color = [0 0 0]; 
            app.RunButton.Enable = "on";
            if app.returnVal == 0 % no error
                app.DoneLamp.Color = [0 1 0]; % green
                % get folders & files and update init setup file
                app.initSetup.INPATH = app.inputPath;
                app.initSetup.OUTPATH = app.outputPath;
                app.initSetup.CALFPATH = [app.calFilePath app.calFileName];
                app.initSetup.SETUPFPATH = app.setupFilePath;
                localInitSetup = app.initSetup;
                % init file is saved in home folder set at program start
                save(app.initFilePath,'-struct','localInitSetup');
            else
                app.ErrorLamp.Color = [1 0 0]; % red
                % do not update init setup file
            end
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            curFold = app.inputPath; % current value
            app.inputPath = uigetdir(app.InputfolderEditField.Value, ...
                'Select INPUT folder');
            % next line prevents GUI going to background
            figure(app.TUNEUIFigure);
            if app.inputPath == 0 % user canceled
                app.inputPath = curFold;
            else
                app.InputfolderEditField.Value = app.inputPath;
            end
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
            curFile = app.calFileName; % current file
            curPath = app.calFilePath; % current path
            [app.calFileName,app.calFilePath] = uigetfile( ...
                app.calFileExt,'Select calibration file',app.calFilePath);
                % [app.calFilePath app.pathSep app.calFileExt],...
                % 'Select calibration file');
            % next line prevents GUI going to background
            figure(app.TUNEUIFigure);
            if app.calFileName == 0 % user canceled
                app.calFileName = curFile;
                app.calFilePath = curPath;
            else
                app.CalibrationfileEditField.Value = ...
                    [app.calFilePath app.calFileName];
                % check for illegal format
                calTable = readtable(app.CalibrationfileEditField.Value);
                if strcmp(class(calTable{:,1}),'double') == 0 || ...
                        strcmp(class(calTable{:,2}),'double') == 0 
                    msg1 = 'Invalid calibration file format.';
                    msg2 = 'Hint: use tab separator and decimal point.';
                    msgbox({msg1;msg2},'Error','error','modal');
                    app.CalibrationfileEditField.Value = value; % reset
                else
                    % cal. file ok, create figure & plot it
                    app.calFigHandle = figure(app.calFigID); 
                    set(gcf,'Name','Calibration file');
                    set(gcf,'NumberTitle','off');
                    set(gcf,'Position',app.calFigPos);
                    plot(calTable{:,1}, calTable{:,2},'o-')
                    grid on
                    xlabel('Frequency / Hz')
                    if calTable{1,2} > 0
                        ylabel('Scale factor / Pa')
                    else
                        ylabel('Sensitivity / dB')
                    end
                    ax = gca; % axes handle
                    ax.XAxis.Exponent = 0; % remove exponent
                end
            end
        end

        % Button pushed function: SelectButton_3
        function SelectButton_3Pushed(app, event)
            curFold = app.outputPath; % current value
            app.outputPath = uigetdir(app.OutputfolderEditField.Value, ...
                'Select OUTPUT folder');
            % next line prevents GUI going to background
            figure(app.TUNEUIFigure);
            if app.outputPath == 0 % user canceled
                app.outputPath = curFold;
            else
                app.OutputfolderEditField.Value = app.outputPath;
                app.defaultFolders = 0; % needed to allow loading setup
            end
        end

        % Value changed function: LogtofileCheckBox
        function LogtofileCheckBoxValueChanged(app, event)
            updateLogAddSetupCheckBox(app);
        end

        % Value changed function: AutoXCheckBox
        function AutoXCheckBoxValueChanged(app, event)
            updateXmaxminFields(app);
        end

        % Value changed function: AutoYCheckBox
        function AutoYCheckBoxValueChanged(app, event)
            updateYmaxminFields(app);
        end

        % Value changed function: KnudsencurvesCheckBox
        function KnudsencurvesCheckBoxValueChanged(app, event)
            updateFromtoSSSpinners(app);
        end

        % Value changed function: DecidecadeSPLCheckBox
        function DecidecadeSPLCheckBoxValueChanged(app, event)
            updateBoxplotDDBandOptions(app);
        end

        % Value changed function: BroadbandPSDCheckBox
        function BroadbandPSDCheckBoxValueChanged(app, event)
            updateAllPSDlinesKnudsenOptions(app);
        end

        % Value changed function: FreesizepositionCheckBox
        function FreesizepositionCheckBoxValueChanged(app, event)
            updateFigureheightwidthOptions(app);
        end

        % Close request function: TUNEUIFigure
        function TUNEUIFigureCloseRequest(app, event)
            close all
            delete(app)            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            curFile = app.setupFileName; % current file
            curPath = app.setupFilePath; % current path
            if app.defaultFolders % program starts for first time
                msgbox('Select input/output folders & cal. file first')
                % otherwise one logfile line is written in default folder
            else
                [app.setupFileName,app.setupFilePath] = uigetfile( ...
                    [app.setupFilePath app.pathSep app.setupFileExt],...
                    'Select setup file');
                % next line prevents GUI going to background
                figure(app.TUNEUIFigure);
                if app.setupFileName == 0 % user canceled
                    app.setupFileName = curFile;
                    app.setupFilePath = curPath;
                else
                    app.SetupfileEditField.Value = ...
                        [app.setupFilePath app.setupFileName];
                    app.Setup = load(app.SetupfileEditField.Value,...
                        app.setupParams{:});
                    valid = readSetup(app); % update GUI parameters
                    if app.LogtofileCheckBox.Value > 0  % logfile enabled
                        diary([app.OutputfolderEditField.Value ...
                                        app.pathSep app.logFileName])
                        disp(char(61*ones(1,80))) % line with '='
                        fprintf('%s :\n Loaded setup %s\n\n', ...
                            datetime('now'), ...
                            app.SetupfileEditField.Value)
                        if valid == 0
                            fprintf(' *** Invalid setup file\n\n')
                        end
                        diary off
                    end
                end
                % check for elements to be enabled/disabled
                updateLogAddSetupCheckBox(app);
                updateXmaxminFields(app);
                updateYmaxminFields(app);
                updateFromtoSSSpinners(app);
                updateBoxplotDDBandOptions(app);
                updateAllPSDlinesKnudsenOptions(app);
                updateFigureheightwidthOptions(app);
                updatePercentileOptions(app);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            curFile = app.setupFileName; % current file
            curPath = app.setupFilePath; % current path
            [app.setupFileName,app.setupFilePath] = uiputfile( ...
                [app.setupFilePath app.pathSep app.setupFileExt],...
                'Enter setup file');
            % next line prevents GUI going to background
            figure(app.TUNEUIFigure);
            if app.setupFileName == 0 % user canceled
                app.setupFileName = curFile;
                app.setupFilePath = curPath;
            else
                app.SetupfileEditField.Value = ...
                    [app.setupFilePath app.setupFileName];
                createSetup(app); % update setup with GUI parameter values
                localSetup = app.Setup; % create structure in workspace
                save(app.SetupfileEditField.Value,'-struct','localSetup'); 
                if app.LogtofileCheckBox.Value > 0
                    diary([app.OutputfolderEditField.Value ...
                                    app.pathSep app.logFileName])
                    fprintf('%s :\n Saved setup %s\n\n', ...
                        datetime('now'), app.SetupfileEditField.Value)
                    diary off
                end
            end
        end

        % Value changed function: SnapshotskipsEditField
        function SnapshotskipsEditFieldValueChanged(app, event)
            value = app.SnapshotskipsEditField.Value;
            if value > 0
                app.SnapshotoverlapSpinner.Value = 0;
            end
        end

        % Value changed function: SnapshotoverlapSpinner
        function SnapshotoverlapSpinnerValueChanged(app, event)
            value = app.SnapshotoverlapSpinner.Value;
            if value > 0
                app.SnapshotskipsEditField.Value = 0;
            end
        end

        % Value changed function: BoxplotCheckBox
        function BoxplotCheckBoxValueChanged(app, event)
            updatePercentileOptions(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TUNEUIFigure = uifigure('Visible', 'off');
            app.TUNEUIFigure.Name = 'TUNE';
            app.TUNEUIFigure.CloseRequestFcn = createCallbackFcn(app, @TUNEUIFigureCloseRequest, true);
            app.StarttimesEditFieldLabel = uilabel(app.TUNEUIFigure);
            app.StarttimesEditFieldLabel.Text = 'Start time / s';
            app.StoptimesEditFieldLabel = uilabel(app.TUNEUIFigure);
            app.StoptimesEditFieldLabel.Text = 'Stop time / s';
            app.SnapshotlengthsEditFieldLabel = uilabel(app.TUNEUIFigure);
            app.SnapshotlengthsEditFieldLabel.Text = 'Snapshot length / s';
            app.SnapoverlapLabel = uilabel(app.TUNEUIFigure);
            app.SnapoverlapLabel.Text = 'Snapshot overlap %';
            app.SnapshotskipsEditFieldLabel = uilabel(app.TUNEUIFigure);
            app.SnapshotskipsEditFieldLabel.Text = 'Snapshot skip / s';
            app.FFTpointsDropDownLabel = uilabel(app.TUNEUIFigure);
            app.FFTpointsDropDownLabel.Text = 'FFT points';
            app.FFToverlapSpinnerLabel = uilabel(app.TUNEUIFigure);
            app.FFToverlapSpinnerLabel.Text = 'FFT overlap %';
            app.HighpasscutoffHzEditFieldLabel = uilabel(app.TUNEUIFigure);
            app.HighpasscutoffHzEditFieldLabel.Text = 'High pass cutoff / Hz';
            app.HighpercentileSpinnerLabel = uilabel(app.TUNEUIFigure);
            app.HighpercentileSpinnerLabel.Text = 'High percentile';
            app.LowpercentileSpinnerLabel = uilabel(app.TUNEUIFigure);
            app.LowpercentileSpinnerLabel.Text = 'Low percentile';
            app.FirstDDbandDropDownLabel = uilabel(app.TUNEUIFigure);
            app.FirstDDbandDropDownLabel.Enable = 'off';
            app.FirstDDbandDropDownLabel.Text = 'First DD band';
            app.LastDDbandDropDownLabel = uilabel(app.TUNEUIFigure);
            app.LastDDbandDropDownLabel.Enable = 'off';
            app.LastDDbandDropDownLabel.Text = 'Last DD band';
            app.XminHzEditFieldLabel = uilabel(app.TUNEUIFigure);
            app.XminHzEditFieldLabel.Text = 'X min / Hz';
            app.YmindBEditFieldLabel = uilabel(app.TUNEUIFigure);
            app.YmindBEditFieldLabel.Text = 'Y min / dB';
            app.XmaxHzEditFieldLabel = uilabel(app.TUNEUIFigure);
            app.XmaxHzEditFieldLabel.Text = 'X max / Hz';
            app.YmaxdBEditFieldLabel = uilabel(app.TUNEUIFigure);
            app.YmaxdBEditFieldLabel.Text = 'Y max / dB';
            app.FigureheightpixEditFieldLabel = uilabel(app.TUNEUIFigure);
            app.FigureheightpixEditFieldLabel.Text = 'Figure height / pix';
            app.FigurewidthpixEditFieldLabel = uilabel(app.TUNEUIFigure);
            app.FigurewidthpixEditFieldLabel.Text = 'Figure width / pix';
            app.fromSSSpinnerLabel = uilabel(app.TUNEUIFigure);
            app.fromSSSpinnerLabel.Enable = 'off';
            app.fromSSSpinnerLabel.Text = 'from SS';
            app.toSSSpinnerLabel = uilabel(app.TUNEUIFigure);
            app.toSSSpinnerLabel.Enable = 'off';
            app.toSSSpinnerLabel.Text = 'to SS';
            app.PauseafterplotsEditFieldLabel = uilabel(app.TUNEUIFigure);
            app.PauseafterplotsEditFieldLabel.Text = {'Pause after'; 'plot / s'};
            app.InputfolderEditFieldLabel = uilabel(app.TUNEUIFigure);
            app.InputfolderEditFieldLabel.Text = 'Input folder';
            app.CalibrationfileEditFieldLabel = uilabel(app.TUNEUIFigure);
            app.CalibrationfileEditFieldLabel.Text = 'Calibration file';
            app.OutputfolderEditFieldLabel = uilabel(app.TUNEUIFigure);
            app.OutputfolderEditFieldLabel.Text = 'Output folder';
            app.RunningLampLabel = uilabel(app.TUNEUIFigure);
            app.RunningLampLabel.Text = 'Running...';
            app.DoneLabel = uilabel(app.TUNEUIFigure);
            app.DoneLabel.Text = 'Done';
            app.ErrorLampLabel = uilabel(app.TUNEUIFigure);
            app.ErrorLampLabel.Text = 'Error';
            app.ExitstatusTextAreaLabel = uilabel(app.TUNEUIFigure);
            app.ExitstatusTextAreaLabel.Text = 'Exit status';
            app.AveragingDropDownLabel = uilabel(app.TUNEUIFigure);
            app.AveragingDropDownLabel.Text = 'Averaging';
            app.WindowDropDownLabel = uilabel(app.TUNEUIFigure);
            app.WindowDropDownLabel.Text = 'Window';
            app.LogtypeDropDownLabel = uilabel(app.TUNEUIFigure);
            app.LogtypeDropDownLabel.Enable = 'off';
            app.LogtypeDropDownLabel.Text = {'Log'; 'type'};
            app.SelectButton = uibutton(app.TUNEUIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select...';
            app.SelectButton_3 = uibutton(app.TUNEUIFigure, 'push');
            app.SelectButton_3.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_3Pushed, true);
            app.SelectButton_3.Text = 'Select...';
            app.SelectButton_2 = uibutton(app.TUNEUIFigure, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select...';
            app.LoadButton = uibutton(app.TUNEUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load...';
            app.SaveButton = uibutton(app.TUNEUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save...';
            app.BroadbandSPLCheckBox = uicheckbox(app.TUNEUIFigure);
            app.BroadbandSPLCheckBox.Text = 'Broadband SPL';
            app.DecidecadeSPLCheckBox = uicheckbox(app.TUNEUIFigure);
            app.DecidecadeSPLCheckBox.ValueChangedFcn = createCallbackFcn(app, @DecidecadeSPLCheckBoxValueChanged, true);
            app.DecidecadeSPLCheckBox.Text = 'Decidecade SPL';
            app.BoxplotCheckBox = uicheckbox(app.TUNEUIFigure);
            app.BoxplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoxplotCheckBoxValueChanged, true);
            app.BoxplotCheckBox.Enable = 'off';
            app.BoxplotCheckBox.Text = 'Box plot';
            app.BroadbandPSDCheckBox = uicheckbox(app.TUNEUIFigure);
            app.BroadbandPSDCheckBox.ValueChangedFcn = createCallbackFcn(app, @BroadbandPSDCheckBoxValueChanged, true);
            app.BroadbandPSDCheckBox.Text = 'Broadband PSD';
            app.SaveJPEGfilesCheckBox = uicheckbox(app.TUNEUIFigure);
            app.SaveJPEGfilesCheckBox.Text = 'Save JPEG files';
            app.SaveTXTfilesCheckBox = uicheckbox(app.TUNEUIFigure);
            app.SaveTXTfilesCheckBox.Text = 'Save TXT files';
            app.StarttimesEditField = uieditfield(app.TUNEUIFigure, 'numeric');
            app.StarttimesEditField.Limits = [0 Inf];
            app.StoptimesEditField = uieditfield(app.TUNEUIFigure, 'numeric');
            app.StoptimesEditField.Limits = [0 Inf];
            app.StoptimesEditField.Value = 10;
            app.SnapshotlengthsEditField = uieditfield(app.TUNEUIFigure, 'numeric');
            app.SnapshotlengthsEditField.Limits = [0.001 Inf];
            app.SnapshotlengthsEditField.Value = 1;
            app.SnapshotskipsEditField = uieditfield(app.TUNEUIFigure, 'numeric');
            app.SnapshotskipsEditField.Limits = [0 Inf];
            app.SnapshotskipsEditField.ValueChangedFcn = createCallbackFcn(app, @SnapshotskipsEditFieldValueChanged, true);
            app.SnapshotoverlapSpinner = uispinner(app.TUNEUIFigure);
            app.SnapshotoverlapSpinner.Step = 25;
            app.SnapshotoverlapSpinner.Limits = [0 75];
            app.SnapshotoverlapSpinner.ValueChangedFcn = createCallbackFcn(app, @SnapshotoverlapSpinnerValueChanged, true);
            app.FFTpointsDropDown = uidropdown(app.TUNEUIFigure);
            app.FFTpointsDropDown.Items = {'128', '256', '512', '1024', '2048', '4096', '8192', '16384', '32768', '65536', '131072', '262144', '524288', '1048576'};
            app.FFTpointsDropDown.ItemsData = {'7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20'};
            app.FFTpointsDropDown.Value = '12';
            app.WindowDropDown = uidropdown(app.TUNEUIFigure);
            app.WindowDropDown.Items = {'none (rect)', 'Hamming', 'Blackman', 'Hann'};
            app.WindowDropDown.ItemsData = {'0', '1', '2', '3'};
            app.WindowDropDown.Value = '1';
            app.FFToverlapSpinner = uispinner(app.TUNEUIFigure);
            app.FFToverlapSpinner.Step = 25;
            app.FFToverlapSpinner.Limits = [0 75];
            app.FFToverlapSpinner.Value = 50;
            app.FirstDDbandDropDown = uidropdown(app.TUNEUIFigure);
            app.FirstDDbandDropDown.Items = {'1 Hz', '1.25 Hz', '1.6 Hz', '2 Hz', '2.5 Hz', '3.15 Hz', '4 Hz', '5 Hz', '6.3 Hz', '8 Hz', '10 Hz', '12.5 Hz', '16 Hz', '20 Hz', '25 Hz', '31.5 Hz', '40 Hz', '50 Hz', '63 Hz', '80 Hz', '100 Hz', '125 Hz', '160 Hz', '200 Hz', '250 Hz', '315 Hz', '400 Hz', '500 Hz', '630 Hz', '800 Hz', '1000 Hz', '1250 Hz', '1600 Hz', '2000 Hz', '2500 Hz', '3150 Hz', '4000 Hz', '5000 Hz', '6300 Hz', '8000 Hz', '10 kHz', '12.5 kHz', '16 kHz', '20 kHz', '25 kHz', '31.5 kHz', '40 kHz', '50 kHz', '63 kHz', '80 kHz', '100 kHz', '125 kHz', '160 kHz', '200 kHz', '250 kHz', '315 kHz', '400 kHz', '500 kHz', '630 kHz', '800 kHz', '1 MHz'};
            app.FirstDDbandDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60', '61'};
            app.FirstDDbandDropDown.Enable = 'off';
            app.FirstDDbandDropDown.Value = '21';
            app.LastDDbandDropDown = uidropdown(app.TUNEUIFigure);
            app.LastDDbandDropDown.Items = {'1 Hz', '1.25 Hz', '1.6 Hz', '2 Hz', '2.5 Hz', '3.15 Hz', '4 Hz', '5 Hz', '6.3 Hz', '8 Hz', '10 Hz', '12.5 Hz', '16 Hz', '20 Hz', '25 Hz', '31.5 Hz', '40 Hz', '50 Hz', '63 Hz', '80 Hz', '100 Hz', '125 Hz', '160 Hz', '200 Hz', '250 Hz', '315 Hz', '400 Hz', '500 Hz', '630 Hz', '800 Hz', '1000 Hz', '1250 Hz', '1600 Hz', '2000 Hz', '2500 Hz', '3150 Hz', '4000 Hz', '5000 Hz', '6300 Hz', '8000 Hz', '10 kHz', '12.5 kHz', '16 kHz', '20 kHz', '25 kHz', '31.5 kHz', '40 kHz', '50 kHz', '63 kHz', '80 kHz', '100 kHz', '125 kHz', '160 kHz', '200 kHz', '250 kHz', '315 kHz', '400 kHz', '500 kHz', '630 kHz', '800 kHz', '1 MHz'};
            app.LastDDbandDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60', '61'};
            app.LastDDbandDropDown.Enable = 'off';
            app.LastDDbandDropDown.Value = '31';
            app.AveragingDropDown = uidropdown(app.TUNEUIFigure);
            app.AveragingDropDown.Items = {'Arithmetic mean', 'Median'};
            app.AveragingDropDown.ItemsData = {'1', '2'};
            app.AveragingDropDown.Value = '1';
            app.HighpercentileSpinner = uispinner(app.TUNEUIFigure);
            app.HighpercentileSpinner.Step = 5;
            app.HighpercentileSpinner.Limits = [50 95];
            app.HighpercentileSpinner.Value = 75;
            app.LowpercentileSpinner = uispinner(app.TUNEUIFigure);
            app.LowpercentileSpinner.Step = 5;
            app.LowpercentileSpinner.Limits = [5 50];
            app.LowpercentileSpinner.Value = 25;
            app.TitleCheckBox = uicheckbox(app.TUNEUIFigure);
            app.TitleCheckBox.Text = 'Title';
            app.LegendCheckBox = uicheckbox(app.TUNEUIFigure);
            app.LegendCheckBox.Text = 'Legend';
            app.AllPSDlinesCheckBox = uicheckbox(app.TUNEUIFigure);
            app.AllPSDlinesCheckBox.Enable = 'off';
            app.AllPSDlinesCheckBox.Text = 'All PSD lines';
            app.KnudsencurvesCheckBox = uicheckbox(app.TUNEUIFigure);
            app.KnudsencurvesCheckBox.ValueChangedFcn = createCallbackFcn(app, @KnudsencurvesCheckBoxValueChanged, true);
            app.KnudsencurvesCheckBox.Enable = 'off';
            app.KnudsencurvesCheckBox.Text = 'Knudsen curves';
            app.fromSSSpinner = uispinner(app.TUNEUIFigure);
            app.fromSSSpinner.Limits = [0 6];
            app.fromSSSpinner.Enable = 'off';
            app.toSSSpinner = uispinner(app.TUNEUIFigure);
            app.toSSSpinner.Limits = [0 6];
            app.toSSSpinner.Enable = 'off';
            app.toSSSpinner.Value = 3;
            app.HighpasscutoffHzEditField = uieditfield(app.TUNEUIFigure, 'numeric');
            app.HighpasscutoffHzEditField.Limits = [0 Inf];
            app.LowpasscutoffHzEditField = uieditfield(app.TUNEUIFigure, 'numeric');
            app.XminHzEditField = uieditfield(app.TUNEUIFigure, 'numeric');
            app.XminHzEditField.Limits = [0 Inf];
            app.XminHzEditField.ValueDisplayFormat = '%g';
            app.XminHzEditField.Value = 10;
            app.XmaxHzEditField = uieditfield(app.TUNEUIFigure, 'numeric');
            app.XmaxHzEditField.Limits = [0 Inf];
            app.XmaxHzEditField.ValueDisplayFormat = '%g';
            app.XmaxHzEditField.Value = 100000;
            app.AutoXCheckBox = uicheckbox(app.TUNEUIFigure);
            app.AutoXCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoXCheckBoxValueChanged, true);
            app.AutoXCheckBox.Text = 'Auto X';
            app.YmindBEditField = uieditfield(app.TUNEUIFigure, 'numeric');
            app.YmindBEditField.ValueDisplayFormat = '%g';
            app.YmindBEditField.Value = 20;
            app.YmaxdBEditField = uieditfield(app.TUNEUIFigure, 'numeric');
            app.YmaxdBEditField.ValueDisplayFormat = '%g';
            app.YmaxdBEditField.Value = 120;
            app.AutoYCheckBox = uicheckbox(app.TUNEUIFigure);
            app.AutoYCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoYCheckBoxValueChanged, true);
            app.AutoYCheckBox.Text = 'Auto Y';
            app.FigurewidthpixEditField = uieditfield(app.TUNEUIFigure, 'numeric');
            app.FigurewidthpixEditField.Limits = [400 4000];
            app.FigurewidthpixEditField.Value = 800;
            app.FigureheightpixEditField = uieditfield(app.TUNEUIFigure, 'numeric');
            app.FigureheightpixEditField.Limits = [300 3000];
            app.FigureheightpixEditField.Value = 600;
            app.FreesizepositionCheckBox = uicheckbox(app.TUNEUIFigure);
            app.FreesizepositionCheckBox.ValueChangedFcn = createCallbackFcn(app, @FreesizepositionCheckBoxValueChanged, true);
            app.FreesizepositionCheckBox.Text = {'Free size'; '& position'};
            app.PauseafterplotsEditField = uieditfield(app.TUNEUIFigure, 'numeric');
            app.PauseafterplotsEditField.Limits = [0 10];
            app.PauseafterplotsEditField.Value = 0.5;
            app.LogtypeDropDown = uidropdown(app.TUNEUIFigure);
            app.LogtypeDropDown.Items = {'Standard', 'Detailed', 'Debug'};
            app.LogtypeDropDown.ItemsData = {'0', '1', '2'};
            app.LogtypeDropDown.Enable = 'off';
            app.LogtypeDropDown.Value = '0';
            app.LogtofileCheckBox = uicheckbox(app.TUNEUIFigure);
            app.LogtofileCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogtofileCheckBoxValueChanged, true);
            app.LogtofileCheckBox.Text = 'Log to file';
            app.AddsetupCheckBox = uicheckbox(app.TUNEUIFigure);
            app.AddsetupCheckBox.Enable = 'off';
            app.AddsetupCheckBox.Text = 'Add setup';
            app.RunButton = uibutton(app.TUNEUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RunningLamp = uilamp(app.TUNEUIFigure);
            app.DoneLamp = uilamp(app.TUNEUIFigure);
            app.ErrorLamp = uilamp(app.TUNEUIFigure);
            app.ExitstatusTextArea = uitextarea(app.TUNEUIFigure);
            app.ExitstatusTextArea.Editable = 'off';
            app.InputfolderEditField = uieditfield(app.TUNEUIFigure, 'text');
            app.InputfolderEditField.Editable = 'off';
            app.OutputfolderEditField = uieditfield(app.TUNEUIFigure, 'text');
            app.OutputfolderEditField.Editable = 'off';
            app.CalibrationfileEditField = uieditfield(app.TUNEUIFigure, 'text');
            app.CalibrationfileEditField.Editable = 'off';
            app.v611Label = uilabel(app.TUNEUIFigure);
            app.v611Label.Text = 'v. 6.11';
            app.infendoffileLabel = uilabel(app.TUNEUIFigure);
            app.infendoffileLabel.Text = 'inf = end of file';
            app.nofilterLabel = uilabel(app.TUNEUIFigure);
            app.nofilterLabel.Text = '0 = no filter';
            app.LowpasscutoffHzLabel = uilabel(app.TUNEUIFigure);
            app.LowpasscutoffHzLabel.Text = 'Low pass cutoff / Hz   ';
            app.SetupfileEditFieldLabel = uilabel(app.TUNEUIFigure);
            app.SetupfileEditFieldLabel.Text = 'Setup file';
            app.SetupfileEditField = uieditfield(app.TUNEUIFigure, 'text');
            app.ZerotopeakSPLCheckBox = uicheckbox(app.TUNEUIFigure);
            app.ZerotopeakSPLCheckBox.Text = 'Zero-to-peak SPL';
            % Show the figure after all components are created
            app.TUNEUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TUNE611

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TUNEUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TUNEUIFigure)
        end
    end
end